classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        NumberofIterationsEditField     matlab.ui.control.NumericEditField
        NumberofIterationsEditFieldLabel  matlab.ui.control.Label
        RedXYEditField                  matlab.ui.control.EditField
        RedXYLabel                      matlab.ui.control.Label
        GreenXYEditField                matlab.ui.control.EditField
        GreenXYEditFieldLabel           matlab.ui.control.Label
        OUTPUTEditField                 matlab.ui.control.EditField
        OUTPUTLabel                     matlab.ui.control.Label
        SelectyouralgorithmButtonGroup  matlab.ui.container.ButtonGroup
        None                            matlab.ui.control.RadioButton
        AStar                           matlab.ui.control.RadioButton
        GradientDescent                 matlab.ui.control.RadioButton
        RightPanel                      matlab.ui.container.Panel
        CheckBox_5                      matlab.ui.control.CheckBox
        CheckBox_4                      matlab.ui.control.CheckBox
        CheckBox_3                      matlab.ui.control.CheckBox
        CheckBox_2                      matlab.ui.control.CheckBox
        CheckBox_1                      matlab.ui.control.CheckBox
        Radius5EditField                matlab.ui.control.NumericEditField
        Radius5EditFieldLabel           matlab.ui.control.Label
        Radius4EditField                matlab.ui.control.NumericEditField
        Radius4EditFieldLabel           matlab.ui.control.Label
        Radius3EditField                matlab.ui.control.NumericEditField
        Radius3EditFieldLabel           matlab.ui.control.Label
        Radius2EditField                matlab.ui.control.NumericEditField
        Radius2EditFieldLabel           matlab.ui.control.Label
        Radius1EditField                matlab.ui.control.NumericEditField
        Radius1EditFieldLabel           matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        GreenCircle
        RedCircle
        Obstacles
        draggingObject
        dragStart
        GDLineHandle
        astarLineHandle
    end

    
    methods (Access = private)

        function startDrag(app, hObject)
            app.draggingObject = hObject;
            cp = app.UIAxes.CurrentPoint;
            app.dragStart = cp(1, 1:2); % Starting x, y in axes
        end

        function doDrag(app)
            if isempty(app.draggingObject)
                return;
            end
    
            cp = app.UIAxes.CurrentPoint;
            currentXY = cp(1, 1:2);
    
            % Compute delta
            delta = currentXY - app.dragStart;
            app.dragStart = currentXY;
        
            % Update position of dragged circle
            pos = app.draggingObject.Position;
            newPos = pos;
            newPos(1:2) = pos(1:2) + delta;
        
            % Clamp to stay within axes
            radius = pos(3:4);  % Width/Height of the circle
        
            newPos(1) = max(0, min(newPos(1), 10 - radius(1)));
            newPos(2) = max(0, min(newPos(2), 10 - radius(2)));
        
            app.draggingObject.Position = newPos;
        end

        % Method to drop the dragged object
        function endDrag(app)
            app.draggingObject = [];
            app.RedXYEditField.Value = sprintf("x: %.3f y: %.3f", app.RedCircle.Position(1), app.RedCircle.Position(2));
            app.GreenXYEditField.Value = sprintf("x: %.3f y: %.3f", app.GreenCircle.Position(1), app.GreenCircle.Position(2));
        end
        % method to take input struct and plot gradient descent line
        function plot_grad_descent(app, param_struct)
            %%%% Gradient descent parameters %%%%
            param_struct.beta = 0.5; %Original: 0.005 %WHAT DOES THIS DO?
            param_struct.gamma = 0.95; %Original: 0.9 %WHAT DOES THIS DO? 
            param_struct.max_iter = app.NumberofIterationsEditField.Value;
            param_struct.min_gradient = 1e-7;
            
            param_struct.cost_coeff = 100; %70
            param_struct.length_multiplier = 10; %100
            
            param_struct.wiggle = 0; %says if we "wiggle" the start guess line. 0 = no, 1 = yes
            param_struct.wiggle_mag = 1; %says the maximum amplitude of the wiggle

            %%%% path planning parameters %%%%


            % app.OUTPUTEditField.Value = string(struct.obs_x)
            [x_list, y_list] = generate_shape_predictionAPP(param_struct);
            for i = 1:length(x_list)
                x_list(i) = x_list(i) + 0;
                y_list(i) = y_list(i) + 0;
            end

            hold(app.UIAxes, 'on');
            app.GDLineHandle = plot(app.UIAxes, x_list, y_list);
            hold(app.UIAxes, 'off');

        end
        function plot_astar(app)
            start_x = round(app.GreenCircle.Position(1) + app.GreenCircle.Position(3)/2); % Get center x of green circle
            start_y = round(app.GreenCircle.Position(2) + app.GreenCircle.Position(4)/2); % Get center y of green circle
            target_x = round(app.RedCircle.Position(1) + app.RedCircle.Position(3)/2);   % Get center x of red circle
            target_y = round(app.RedCircle.Position(2) + app.RedCircle.Position(4)/2);   % Get center y of red circle
            
            obstacle_list = []; % Initialize empty obstacle list
            obstacle_radii = [];
            for i = 1:length(app.Obstacles)
                if strcmp(app.Obstacles(i).Visible, 'on')
                    obstacle_x = round(app.Obstacles(i).Position(1) + app.Obstacles(i).Position(3)/2);
                    obstacle_y = round(app.Obstacles(i).Position(2) + app.Obstacles(i).Position(4)/2);
                    obstacle_list = [obstacle_list; obstacle_x, obstacle_y]; % Add obstacle to the list
                    obstacle_radii = [obstacle_radii; app.Obstacles(i).Position(3)/2];
                end
            end
            
            max_x = 10;
            max_y = 10;
            
            allow_diagonal = true;  % Allow diagonal movements for a jagged path
            interpolation_distance = 0.1; % Interpolate points every 0.1 units
            grid_resolution = 100;  % Example: Increase resolution by a factor of 3
            robot_radius = 1.0;
            [x_list, y_list] = a_star_algorithm(start_x, start_y, target_x, target_y, obstacle_list, max_x, max_y, allow_diagonal, interpolation_distance, grid_resolution, robot_radius);                
            % Plot the A* path on the axes
            if ~isempty(x_list)
                hold(app.UIAxes, 'on');
                app.astarLineHandle = plot(app.UIAxes, x_list, y_list, 'b-', 'LineWidth', 2);
                hold(app.UIAxes, 'off');
            else
                app.OUTPUTEditField.Value = 'A*: No path found!';
                disp('A* in app2: No path to plot'); % add this
            end
        end

        function plot_fmincon(app)
            start_x = round(app.GreenCircle.Position(1) + app.GreenCircle.Position(3)/2); % Get center x of green circle
            start_y = round(app.GreenCircle.Position(2) + app.GreenCircle.Position(4)/2); % Get center y of green circle
            target_x = round(app.RedCircle.Position(1) + app.RedCircle.Position(3)/2);   % Get center x of red circle
            target_y = round(app.RedCircle.Position(2) + app.RedCircle.Position(4)/2);   % Get center y of red circle
            obstacle_list = []; % Initialize empty obstacle list
            obstacle_radii = [];
            for i = 1:length(app.Obstacles)
                if strcmp(app.Obstacles(i).Visible, 'on')
                    obstacle_x = round(app.Obstacles(i).Position(1) + app.Obstacles(i).Position(3)/2);
                    obstacle_y = round(app.Obstacles(i).Position(2) + app.Obstacles(i).Position(4)/2);
                    obstacle_list = [obstacle_list; obstacle_x, obstacle_y]; % Add obstacle to the list
                    obstacle_radii = [obstacle_radii; app.Obstacles(i).Position(3)/2];
                end
            end
            
            max_x = 10;
            max_y = 10;
            grid_resolution = 10;

            [x_list, y_list, path_found] = path_planning_fmincon(start_x, start_y, target_x, target_y, obstacle_list, obstacle_radii, max_x, max_y, grid_resolution)
            if path_found
                hold(app.UIAxes, 'on');
                app.astarLineHandle = plot(app.UIAxes, x_list, y_list, 'b-', 'LineWidth', 2);
                hold(app.UIAxes, 'off');
            else
                app.OUTPUTEditField.Value = 'A*: No path found!';
                disp('A* in app2: No path to plot'); % add this
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.GreenCircle = rectangle(app.UIAxes, 'Position', [0.75, 0.75, 0.5, 0.5], ...
            'Curvature', [1, 1], 'FaceColor', 'green', 'ButtonDownFcn', @(src, event)startDrag(app, src));
    
            % Create the red circle
            app.RedCircle = rectangle(app.UIAxes, 'Position', [7.75, 7.75, 0.5, 0.5], ...
                'Curvature', [1, 1], 'FaceColor', 'red', 'ButtonDownFcn', @(src, event)startDrag(app, src));
            Obstacle1 = rectangle(app.UIAxes, 'Position', [1.1, 2.3, 2, 2], ...
                'Curvature', [1, 1], 'FaceColor', 'yellow', 'ButtonDownFcn', @(src, event)startDrag(app, src));
            Obstacle2 = rectangle(app.UIAxes, 'Position', [5.5, 2.5, 2, 2], ...
                'Curvature', [1, 1], 'FaceColor', 'yellow', 'ButtonDownFcn', @(src, event)startDrag(app, src));
            Obstacle3 = rectangle(app.UIAxes, 'Position', [2.5, 5.5, 1, 1], ...
                'Curvature', [1, 1], 'FaceColor', 'yellow', 'ButtonDownFcn', @(src, event)startDrag(app, src));
            Obstacle4 = rectangle(app.UIAxes, 'Position', [6.0, 5.5, 1, 1], ...
                'Curvature', [1, 1], 'FaceColor', 'yellow', 'ButtonDownFcn', @(src, event)startDrag(app, src));
            Obstacle5 = rectangle(app.UIAxes, 'Position', [3.75, 4.0, 1, 1], ...
                'Curvature', [1, 1], 'FaceColor', 'yellow', 'ButtonDownFcn', @(src, event)startDrag(app, src));
            app.Obstacles = [Obstacle1, Obstacle2, Obstacle3, Obstacle4, Obstacle5];

            xlim(app.UIAxes, [0 10]);
            ylim(app.UIAxes, [0 10]);
            axis(app.UIAxes, 'equal');         % Keep aspect ratio
            app.UIAxes.XLimMode = 'manual';    % Lock X limits
            app.UIAxes.YLimMode = 'manual';    % Lock Y limits
            % Initialize dragging object
            
            % Add listeners to show coordinates when dragged
            % addlistener(app.GreenCircle, 'MovingROI', ...
            %     @(src, evt) disp(['Green: ' mat2str(src.Center)]));
            % 
            % addlistener(app.RedCircle, 'MovingROI', ...
            %     @(src, evt) disp(['Red: ' mat2str(src.Center)]));
            %             % Set up dragging functionality
            app.UIFigure.WindowButtonMotionFcn = @(src, event)doDrag(app);
            app.UIFigure.WindowButtonUpFcn = @(src, event)endDrag(app);
             
            app.RedXYEditField.Value = sprintf("x: %.3f y: %.3f", app.RedCircle.Position(1), app.RedCircle.Position(2));
            app.GreenXYEditField.Value = sprintf("x: %.3f y: %.3f", app.GreenCircle.Position(1), app.GreenCircle.Position(2));
        end

        % Value changed function: OUTPUTEditField
        function OUTPUTEditFieldValueChanged(app, event)
            value = app.OUTPUTEditField.Value;

        end

        % Selection changed function: SelectyouralgorithmButtonGroup
        function SelectyouralgorithmButtonGroupSelectionChanged(app, event)
            selectedButton = event.NewValue;
                if ~isempty(app.astarLineHandle) && isvalid(app.astarLineHandle)
                    delete(app.astarLineHandle);
                end
                if ~isempty(app.GDLineHandle) && isvalid(app.GDLineHandle)
                    delete(app.GDLineHandle);
                end
            if selectedButton == app.GradientDescent
                app.OUTPUTEditField.Value = "Gradient Descent";
                param_struct = struct;
                param_struct.r0 = [app.GreenCircle.Position(1) + 0.25; app.GreenCircle.Position(2) + 0.25]; %start coords
                param_struct.rn = [app.RedCircle.Position(1) + 0.25; app.RedCircle.Position(2) + 0.25]; %end coords
                param_struct.num_links = 80;
                param_struct.obs_x = [];
                param_struct.obs_y = [];
                param_struct.obs_r = [];
                for i = 1:length(app.Obstacles)
                    if strcmp(app.Obstacles(i).Visible,'on')
                        param_struct.obs_x(end+1) = app.Obstacles(i).Position(1) + app.Obstacles(i).Position(3)/2;
                        param_struct.obs_y(end+1) = app.Obstacles(i).Position(2) + app.Obstacles(i).Position(3)/2;
                        param_struct.obs_r(end+1) = app.Obstacles(i).Position(3)/2;
                    end
                end
                plot_grad_descent(app, param_struct)


            elseif selectedButton == app.AStar
                app.OUTPUTEditField.Value = "A*";
                plot_astar(app)
            % elseif selectedButton == app.fmincon
            %     app.OUTPUTEditField.Value = "A*";
            %     plot_fmincon(app)


            elseif selectedButton == app.None
                app.OUTPUTEditField.Value = "";
            end

        end

        % Button down function: UIFigure
        function Drag(app, event)

        end

        % Value changed function: GreenXYEditField
        function GreenXYEditFieldValueChanged(app, event)
            value = app.GreenXYEditField.Value;
            
        end

        % Value changed function: RedXYEditField
        function RedXYEditFieldValueChanged(app, event)
            value = app.RedXYEditField.Value;
            
        end

        % Callback function
        function Radius1SliderValueChanged(app, event)

        end

        % Callback function
        function Radius1SliderValueChanging(app, event)
            value = event.Value;
    
            % % Sanitize value
            % if isempty(value) || ~isscalar(value) || isnan(value)
            %     value = 1;
            % end
            center = app.Obstacles(1).Position(1:2) + app.Obstacles(1).Position(3:4)/2;

            % Remove previous obstacle if it exists
            if isvalid(app.Obstacles(1))
                delete(app.Obstacles(1));
            end
        
            % Draw new circle

            x = center(1) - value/2;
            y = center(2) - value/2;

            app.Obstacles(1) = rectangle(app.UIAxes, ...
                'Position', [x, y, double(value), double(value)], ...
                'Curvature', [1, 1], ...
                'FaceColor', 'yellow', ...
                'ButtonDownFcn', @(src, event) startDrag(app, src));
        end

        % Value changed function: Radius1EditField
        function Radius1EditFieldValueChanged(app, event)
            value = app.Radius1EditField.Value;    
            % % Sanitize value
            % if isempty(value) || ~isscalar(value) || isnan(value)
            %     value = 1;
            % end
            center = app.Obstacles(1).Position(1:2) + app.Obstacles(1).Position(3:4)/2;

            % Remove previous obstacle if it exists
            if isvalid(app.Obstacles(1))
                delete(app.Obstacles(1));
            end
        
            % Draw new circle

            x = center(1) - value/2;
            y = center(2) - value/2;

            app.Obstacles(1) = rectangle(app.UIAxes, ...
                'Position', [x, y, double(value), double(value)], ...
                'Curvature', [1, 1], ...
                'FaceColor', 'yellow', ...
                'ButtonDownFcn', @(src, event) startDrag(app, src));
        end

        % Value changed function: Radius2EditField
        function Radius2EditFieldValueChanged(app, event)
            value = app.Radius2EditField.Value;
            % % Sanitize value
            % if isempty(value) || ~isscalar(value) || isnan(value)
            %     value = 1;
            % end
            center = app.Obstacles(2).Position(1:2) +app.Obstacles(2).Position(3:4)/2;

            % Remove previous obstacle if it exists
            if isvalid(app.Obstacles(2))
                delete(app.Obstacles(2));
            end
        
            % Draw new circle

            x = center(1) - value/2;
            y = center(2) - value/2;

            app.Obstacles(2) = rectangle(app.UIAxes, ...
                'Position', [x, y, double(value), double(value)], ...
                'Curvature', [1, 1], ...
                'FaceColor', 'yellow', ...
                'ButtonDownFcn', @(src, event) startDrag(app, src));
        end

        % Value changed function: Radius3EditField
        function Radius3EditFieldValueChanged(app, event)
            value = app.Radius3EditField.Value;
            % % Sanitize value
            % if isempty(value) || ~isscalar(value) || isnan(value)
            %     value = 1;
            % end
            center = app.Obstacles(3).Position(1:2) + app.Obstacles(3).Position(3:4)/2;

            % Remove previous obstacle if it exists
            if isvalid(app.Obstacles(3))
                delete(app.Obstacles(3));
            end
        
            % Draw new circle

            x = center(1) - value/2;
            y = center(2) - value/2;

            app.Obstacles(3)= rectangle(app.UIAxes, ...
                'Position', [x, y, double(value), double(value)], ...
                'Curvature', [1, 1], ...
                'FaceColor', 'yellow', ...
                'ButtonDownFcn', @(src, event) startDrag(app, src));
        end

        % Value changed function: Radius4EditField
        function Radius4EditFieldValueChanged(app, event)
            value = app.Radius4EditField.Value;
            % % Sanitize value
            % if isempty(value) || ~isscalar(value) || isnan(value)
            %     value = 1;
            % end
            center = app.Obstacles(4).Position(1:2) + app.Obstacles(4).Position(3:4)/2;

            % Remove previous obstacle if it exists
            if isvalid(app.Obstacles(4))
                delete(app.Obstacles(4));
            end
        
            % Draw new circle

            x = center(1) - value/2;
            y = center(2) - value/2;

            app.Obstacles(4)= rectangle(app.UIAxes, ...
                'Position', [x, y, double(value), double(value)], ...
                'Curvature', [1, 1], ...
                'FaceColor', 'yellow', ...
                'ButtonDownFcn', @(src, event) startDrag(app, src));
        end

        % Value changed function: Radius5EditField
        function Radius5EditFieldValueChanged(app, event)
            value = app.Radius5EditField.Value;
            % % Sanitize value
            % if isempty(value) || ~isscalar(value) || isnan(value)
            %     value = 1;
            % end
            center = app.Obstacles(5).Position(1:2) + app.Obstacles(5).Position(3:4)/2;

            % Remove previous obstacle if it exists
            if isvalid(app.Obstacles(5))
                delete(app.Obstacles(5));
            end
        
            % Draw new circle

            x = center(1) - value/2;
            y = center(2) - value/2;

            app.Obstacles(5) = rectangle(app.UIAxes, ...
                'Position', [x, y, double(value), double(value)], ...
                'Curvature', [1, 1], ...
                'FaceColor', 'yellow', ...
                'ButtonDownFcn', @(src, event) startDrag(app, src));
        end

        % Value changed function: CheckBox_1
        function CheckBox_1ValueChanged(app, event)
            value = app.CheckBox_1.Value;
           if value==true
               app.Obstacles(1).Visible = 'on';
           else
               app.Obstacles(1).Visible = 'off';
           end
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            value = app.CheckBox_2.Value;
           if value==true
               app.Obstacles(2).Visible = 'on';
           else
               app.Obstacles(2).Visible = 'off';
           end
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
            value = app.CheckBox_3.Value;
           if value==true
               app.Obstacles(3).Visible = 'on';
           else
               app.Obstacles(3).Visible = 'off';
           end
        end

        % Value changed function: CheckBox_4
        function CheckBox_4ValueChanged(app, event)
            value = app.CheckBox_4.Value;
           if value==true
               app.Obstacles(4).Visible = 'on';
           else
               app.Obstacles(4).Visible = 'off';
           end
        end

        % Value changed function: CheckBox_5
        function CheckBox_5ValueChanged(app, event)
            value = app.CheckBox_5.Value;
           if value==true
               app.Obstacles(5).Visible = 'on';
           else
               app.Obstacles(5).Visible = 'off';
           end
        end

        % Value changed function: NumberofIterationsEditField
        function NumberofIterationsEditFieldValueChanged(app, event)
            value = app.NumberofIterationsEditField.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @Drag, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SelectyouralgorithmButtonGroup = uibuttongroup(app.LeftPanel);
            app.SelectyouralgorithmButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectyouralgorithmButtonGroupSelectionChanged, true);
            app.SelectyouralgorithmButtonGroup.Title = 'Select your algorithm';
            app.GradientDescent = uiradiobutton(app.SelectyouralgorithmButtonGroup);
            app.GradientDescent.Text = 'Gradient Descent';
            app.AStar = uiradiobutton(app.SelectyouralgorithmButtonGroup);
            app.AStar.Text = 'A*';
            app.None = uiradiobutton(app.SelectyouralgorithmButtonGroup);
            app.None.Text = 'None';
            app.None.Value = true;
            app.OUTPUTLabel = uilabel(app.LeftPanel);
            app.OUTPUTLabel.Text = 'OUTPUT  ';
            app.OUTPUTEditField = uieditfield(app.LeftPanel, 'text');
            app.OUTPUTEditField.ValueChangedFcn = createCallbackFcn(app, @OUTPUTEditFieldValueChanged, true);
            app.GreenXYEditFieldLabel = uilabel(app.LeftPanel);
            app.GreenXYEditFieldLabel.Text = 'Green X,Y';
            app.GreenXYEditField = uieditfield(app.LeftPanel, 'text');
            app.GreenXYEditField.ValueChangedFcn = createCallbackFcn(app, @GreenXYEditFieldValueChanged, true);
            app.RedXYLabel = uilabel(app.LeftPanel);
            app.RedXYLabel.Text = 'Red X,Y     ';
            app.RedXYEditField = uieditfield(app.LeftPanel, 'text');
            app.RedXYEditField.ValueChangedFcn = createCallbackFcn(app, @RedXYEditFieldValueChanged, true);
            app.NumberofIterationsEditFieldLabel = uilabel(app.LeftPanel);
            app.NumberofIterationsEditFieldLabel.Text = {'Number of '; 'Iterations'};
            app.NumberofIterationsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NumberofIterationsEditField.RoundFractionalValues = 'on';
            app.NumberofIterationsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofIterationsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofIterationsEditFieldValueChanged, true);
            app.NumberofIterationsEditField.Value = 100;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Radius1EditFieldLabel = uilabel(app.RightPanel);
            app.Radius1EditFieldLabel.Text = 'Radius 1';
            app.Radius1EditField = uieditfield(app.RightPanel, 'numeric');
            app.Radius1EditField.ValueChangedFcn = createCallbackFcn(app, @Radius1EditFieldValueChanged, true);
            app.Radius1EditField.Value = 2;
            app.Radius2EditFieldLabel = uilabel(app.RightPanel);
            app.Radius2EditFieldLabel.Text = 'Radius 2';
            app.Radius2EditField = uieditfield(app.RightPanel, 'numeric');
            app.Radius2EditField.ValueChangedFcn = createCallbackFcn(app, @Radius2EditFieldValueChanged, true);
            app.Radius2EditField.Value = 2;
            app.Radius3EditFieldLabel = uilabel(app.RightPanel);
            app.Radius3EditFieldLabel.Text = 'Radius 3';
            app.Radius3EditField = uieditfield(app.RightPanel, 'numeric');
            app.Radius3EditField.ValueChangedFcn = createCallbackFcn(app, @Radius3EditFieldValueChanged, true);
            app.Radius3EditField.Value = 1;
            app.Radius4EditFieldLabel = uilabel(app.RightPanel);
            app.Radius4EditFieldLabel.Text = 'Radius 4';
            app.Radius4EditField = uieditfield(app.RightPanel, 'numeric');
            app.Radius4EditField.ValueChangedFcn = createCallbackFcn(app, @Radius4EditFieldValueChanged, true);
            app.Radius4EditField.Value = 1;
            app.Radius5EditFieldLabel = uilabel(app.RightPanel);
            app.Radius5EditFieldLabel.Text = 'Radius 5';
            app.Radius5EditField = uieditfield(app.RightPanel, 'numeric');
            app.Radius5EditField.ValueChangedFcn = createCallbackFcn(app, @Radius5EditFieldValueChanged, true);
            app.Radius5EditField.Value = 1;
            app.CheckBox_1 = uicheckbox(app.RightPanel);
            app.CheckBox_1.ValueChangedFcn = createCallbackFcn(app, @CheckBox_1ValueChanged, true);
            app.CheckBox_1.Text = '';
            app.CheckBox_1.Value = true;
            app.CheckBox_2 = uicheckbox(app.RightPanel);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '';
            app.CheckBox_2.Value = true;
            app.CheckBox_3 = uicheckbox(app.RightPanel);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Text = '';
            app.CheckBox_3.Value = true;
            app.CheckBox_4 = uicheckbox(app.RightPanel);
            app.CheckBox_4.ValueChangedFcn = createCallbackFcn(app, @CheckBox_4ValueChanged, true);
            app.CheckBox_4.Text = '';
            app.CheckBox_4.Value = true;
            app.CheckBox_5 = uicheckbox(app.RightPanel);
            app.CheckBox_5.ValueChangedFcn = createCallbackFcn(app, @CheckBox_5ValueChanged, true);
            app.CheckBox_5.Text = '';
            app.CheckBox_5.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end